classdef covid19_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TheVisualizationofCOVID19RelatedDataUIFigure  matlab.ui.Figure
        YASHKHWAHISHSWETAYOGESHLabel  matlab.ui.control.Label
        OptionButtonGroup             matlab.ui.container.ButtonGroup
        DailyButton                   matlab.ui.control.RadioButton
        CumulativeButton              matlab.ui.control.RadioButton
        AverageSlider                 matlab.ui.control.Slider
        AveragedofdaysSliderLabel     matlab.ui.control.Label
        DataofPlotButtonGroup         matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.RadioButton
        DeathsButton                  matlab.ui.control.RadioButton
        CasesButton                   matlab.ui.control.RadioButton
        StateListBox                  matlab.ui.control.ListBox
        StateRegionProvinceLabel      matlab.ui.control.Label
        CountryListBox                matlab.ui.control.ListBox
        CountryLabel                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Countries Country
        CountryNameList
        Date
        CurrentCountry
        CurrentState
        DayAverage
        Type
        Option
    end
    
    methods (Access = private)
        function PlotCurves(app)
            barcolor = [0 0 255]/255;
            linecolor = [255 0 0]/255;
            avr = app.DayAverage;
            tp = app.Type;
            cc = app.CurrentCountry;
            cs = app.CurrentState;
            op = app.Option;
            
            dname = tp;
            if isequal(tp,'Both')
                dname = 'Cases and Deaths';
            end
            app.UIAxes.Title.String = [op,' Numbers of ',dname ,' in ',cs,' State(Province) of ', cc, ' (',num2str(avr),'-day mean)'];
            
            Flag = [strcmp(cc,'All Countries'), strcmp(cs,'all'), strcmp(tp,{'Cases','Deaths','Both'}), strcmp(op,{'Cumulative','Daily'})];
            code = bin2dec(num2str(Flag));
            indexc = strcmp(app.CountryNameList,cc);
            if sum(indexc) == 0
                indexs = [];
            else
                indexs = strcmp(app.Countries(indexc).StateNameList, cs);
            end
            for i = 1:length(app.CountryNameList)
                YAA(i,:) = app.Countries(i).DailyCases;
                SHH(i,:) = app.Countries(i).DailyDeaths;
                SRR(i,:) = app.Countries(i).CumulativeCases;
                VAS(i,:) = app.Countries(i).CumulativeDeaths;
            end
            [r,c] = size(SRR);
            cases = [];
            deaths = [];
            switch code
                case {5}
                    cases = movmean(app.Countries(indexc).States(indexs).DailyCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Countries(indexc).States(indexs).DailyDeaths, avr,'Endpoints','discard');
                case {37}
                    cases = movmean(app.Countries(indexc).DailyCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Countries(indexc).DailyDeaths, avr,'Endpoints','discard');
                case {69,101}
                    cases = movmean(sum(YAA,1), avr,'Endpoints','discard');
                    deaths = movmean(sum(SHH,1), avr,'Endpoints','discard');
                case {6}
                    cases = movmean(app.Countries(indexc).States(indexs).CumulativeCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Countries(indexc).States(indexs).CumulativeDeaths, avr,'Endpoints','discard');
                case {38}
                    cases = movmean(app.Countries(indexc).CumulativeCases, avr,'Endpoints','discard');
                    deaths = movmean(app.Countries(indexc).CumulativeDeaths, avr,'Endpoints','discard');
                case {70,102}
                    cases = movmean(sum(SRR,1), avr,'Endpoints','discard');
                    deaths = movmean(sum(VAS,1), avr,'Endpoints','discard');
                case {9}
                    deaths = movmean(app.Countries(indexc).States(indexs).DailyDeaths, avr,'Endpoints','discard');
                case {41}
                    deaths = movmean(app.Countries(indexc).DailyDeaths, avr,'Endpoints','discard');
                case {73,105}
                    deaths = movmean(sum(SHH,1), avr,'Endpoints','discard');
                case {10}
                    deaths = movmean(app.Countries(indexc).States(indexs).CumulativeDeaths, avr,'Endpoints','discard');
                case {42}
                    deaths = movmean(app.Countries(indexc).CumulativeDeaths, avr,'Endpoints','discard');
                case {74,106}
                    deaths = movmean(sum(SRR,1), avr,'Endpoints','discard');
                case {17}
                    cases = movmean(app.Countries(indexc).States(indexs).DailyCases, avr,'Endpoints','discard');
                case {49}
                    cases = movmean(app.Countries(indexc).DailyCases, avr,'Endpoints','discard');
                case {81,113}
                    cases = movmean(sum(YAA,1), avr,'Endpoints','discard');
                case {18}
                    cases = movmean(app.Countries(indexc).States(indexs).CumulativeCases, avr,'Endpoints','discard');
                case {50}
                    cases = movmean(app.Countries(indexc).CumulativeCases, avr,'Endpoints','discard');
                case {82,114}
                    cases = movmean(sum(SRR,1), avr,'Endpoints','discard');
            end
            if ~isempty(cases) && ~isempty(deaths)
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes,cases, 0.5,'FaceColor',barcolor);
                app.UIAxes.YColor = barcolor;
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes,deaths,'LineWidth',2,'Color',linecolor);
                app.UIAxes.YColor = linecolor;
                app.UIAxes.XLim = [1,length(cases)];
            else
                if isempty(deaths)
                    yyaxis(app.UIAxes, 'left');
                    bar(app.UIAxes,cases, 0.5,'FaceColor',barcolor);
                    app.UIAxes.YColor = barcolor;
                    yyaxis(app.UIAxes, 'right');
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [1,length(cases)];
                else
                    yyaxis(app.UIAxes, 'right');
                    plot(app.UIAxes,deaths,'LineWidth',2,'Color',linecolor);
                    app.UIAxes.YColor = linecolor;
                    yyaxis(app.UIAxes, 'left');
                    cla(app.UIAxes);
                    app.UIAxes.XLim = [1,length(deaths)];
                end
            end
            app.UIAxes.YLim = [0,inf];
            lx = length(app.UIAxes.XTick);
            ld = length(app.Date);
            cut = ceil((avr-1)/2);
            app.UIAxes.XTickLabel = app.Date(1+cut:ceil(ld/lx):end-cut);
            app.UIAxes.XTickLabelRotation = 60;
            app.UIAxes.FontWeight = 'bold';
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("covid_data.mat");
            Data = covid_data;
            %             data = cell2mat(Data(2:end,3:end));
            app.Date = Data(1,3:end);
            app.CountryNameList = unique(Data(2:end,1));
            for i = 1:length(app.CountryNameList)
                name = app.CountryNameList{i};
                index = strcmp(name,Data(:,1));
                app.Countries(i) = Country(name, Data(index,2), Data(index,3:end));
            end
            app.CountryListBox.Items = [ 'All Countries'; app.CountryNameList ];
            app.CurrentCountry = 'All Countries';
            app.StateListBox.Items = {'all'};
            app.CurrentState = 'all';
            app.Type = 'Cases';
            app.Option = 'Cumulative';
            app.DayAverage = 1;
            PlotCurves(app);
        end

        % Value changing function: AverageSlider
        function AverageSliderValueChanging(app, event)
            app.DayAverage = round(event.Value);
            PlotCurves(app);
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.CurrentCountry = app.CountryListBox.Value;
            
            indexc = strcmp(app.CountryNameList,app.CurrentCountry);
            if sum(indexc) == 0
                app.StateListBox.Items = {'all'};
            else
                app.StateListBox.Items = ['all'; app.Countries(indexc).StateNameList];
            end
            app.CurrentState = 'all';
            PlotCurves(app);
        end

        % Selection changed function: DataofPlotButtonGroup
        function DataofPlotButtonGroupSelectionChanged(app, event)
            app.Type = app.DataofPlotButtonGroup.SelectedObject.Text;
            PlotCurves(app);
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.Option = app.OptionButtonGroup.SelectedObject.Text;
            PlotCurves(app);
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            app.CurrentState = app.StateListBox.Value;
            PlotCurves(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TheVisualizationofCOVID19RelatedDataUIFigure = uifigure('Visible', 'off');
            app.TheVisualizationofCOVID19RelatedDataUIFigure.Name = 'The Visualization of COVID-19 Related Data';
            app.UIAxes = uiaxes(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            title(app.UIAxes, 'Daily Numbers of Cases and Deaths in China (7-day mean)')
            app.CountryLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.CountryLabel.Text = 'Country: ';
            app.CountryListBox = uilistbox(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.CountryListBox.Items = {'Empty'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'Empty';
            app.StateRegionProvinceLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.StateRegionProvinceLabel.Text = 'State\Region\Province: ';
            app.StateListBox = uilistbox(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.StateListBox.Items = {'Empty'};
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.StateListBox.Value = 'Empty';
            app.DataofPlotButtonGroup = uibuttongroup(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.DataofPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataofPlotButtonGroupSelectionChanged, true);
            app.DataofPlotButtonGroup.Title = 'Data of  Plot';
            app.CasesButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DataofPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.AveragedofdaysSliderLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.AveragedofdaysSliderLabel.Text = 'Averaged # of days';
            app.AverageSlider = uislider(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.AverageSlider.Limits = [1 15];
            app.AverageSlider.ValueChangingFcn = createCallbackFcn(app, @AverageSliderValueChanging, true);
            app.AverageSlider.MinorTicks = [];
            app.AverageSlider.Value = 1;
            app.OptionButtonGroup = uibuttongroup(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.YASHKHWAHISHSWETAYOGESHLabel = uilabel(app.TheVisualizationofCOVID19RelatedDataUIFigure);
            app.YASHKHWAHISHSWETAYOGESHLabel.Text = 'YASH || KHWAHISH || SWETA|| YOGESH';
            % Show the figure after all components are created
            app.TheVisualizationofCOVID19RelatedDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid19_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TheVisualizationofCOVID19RelatedDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TheVisualizationofCOVID19RelatedDataUIFigure)
        end
    end
end